classdef BG_globalThresholding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        maxEditField                  matlab.ui.control.NumericEditField
        minEditField                  matlab.ui.control.NumericEditField
        OKButton                      matlab.ui.control.Button
        backgroundmaxintensitySlider  matlab.ui.control.Slider
        backgroundmaxintensitySliderLabel  matlab.ui.control.Label
        backgroundminintensitySlider  matlab.ui.control.Slider
        backgroundminintensitySliderLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        callingApp % Description
        maxI;
        minI;
    end
    
    methods (Access = public)
        
        function updateBG(app)
            app.callingApp.BG=app.callingApp.dat>=app.backgroundminintensitySlider.Value...
                &app.callingApp.dat<=app.backgroundmaxintensitySlider.Value;
            app.callingApp.visBG();
        end

        function update1zBG(app)
            app.callingApp.BG(:,:,app.callingApp.zsliceEditField.Value)...
                =app.callingApp.dat(:,:,app.callingApp.zsliceEditField.Value)>=app.backgroundminintensitySlider.Value...
                &app.callingApp.dat(:,:,app.callingApp.zsliceEditField.Value)<=app.backgroundmaxintensitySlider.Value;
            app.callingApp.vis1zBG();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            app.callingApp=callingApp;
            % app.maxI=max(app.callingApp.dat,[],"all");
            app.maxI=app.callingApp.maxintensityEditField.Value;
            app.backgroundmaxintensitySlider.Limits=[0 app.maxI];
            app.backgroundmaxintensitySlider.Value=app.maxI;
            app.maxEditField.Value=app.backgroundmaxintensitySlider.Value;

            app.backgroundminintensitySlider.Limits=[0 app.maxI];
            app.backgroundminintensitySlider.Value=0;
            app.minEditField.Value=app.backgroundminintensitySlider.Value;

            updateBG(app)
        end

        % Value changing function: backgroundminintensitySlider
        function backgroundminintensitySliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue>app.backgroundmaxintensitySlider.Value
                app.backgroundmaxintensitySlider.Value=changingValue;
            end
            app.minEditField.Value=round(changingValue);
            % update1zBG(app)
        end

        % Value changing function: backgroundmaxintensitySlider
        function backgroundmaxintensitySliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue<app.backgroundminintensitySlider.Value
                app.backgroundminintensitySlider.Value=changingValue;
            end
            app.maxEditField.Value=round(changingValue);
            % update1zBG(app)
        end

        % Value changed function: backgroundminintensitySlider
        function backgroundminintensitySliderValueChanged(app, event)
            % value = app.backgroundminintensitySlider.Value;
            updateBG(app)
        end

        % Value changed function: backgroundmaxintensitySlider
        function backgroundmaxintensitySliderValueChanged(app, event)
            % value = app.backgroundmaxintensitySlider.Value;
            updateBG(app)
        end

        % Value changed function: minEditField
        function minEditFieldValueChanged(app, event)
            % value = app.minEditField.Value;
            app.minEditField.Value=min(max(app.minEditField.Value,0),app.maxI);
            app.backgroundminintensitySlider.Value=app.minEditField.Value;
            updateBG(app)
        end

        % Value changed function: maxEditField
        function maxEditFieldValueChanged(app, event)
            % value = app.maxEditField.Value;
            app.maxEditField.Value=min(max(app.maxEditField.Value,0),app.maxI);
            app.backgroundmaxintensitySlider.Value=app.maxEditField.Value;
            updateBG(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.backgroundminintensitySliderLabel = uilabel(app.UIFigure);
            app.backgroundminintensitySliderLabel.Text = 'background min intensity';
            app.backgroundminintensitySlider = uislider(app.UIFigure);
            app.backgroundminintensitySlider.ValueChangedFcn = createCallbackFcn(app, @backgroundminintensitySliderValueChanged, true);
            app.backgroundminintensitySlider.ValueChangingFcn = createCallbackFcn(app, @backgroundminintensitySliderValueChanging, true);
            app.backgroundmaxintensitySliderLabel = uilabel(app.UIFigure);
            app.backgroundmaxintensitySliderLabel.Text = 'background max intensity';
            app.backgroundmaxintensitySlider = uislider(app.UIFigure);
            app.backgroundmaxintensitySlider.ValueChangedFcn = createCallbackFcn(app, @backgroundmaxintensitySliderValueChanged, true);
            app.backgroundmaxintensitySlider.ValueChangingFcn = createCallbackFcn(app, @backgroundmaxintensitySliderValueChanging, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.minEditField = uieditfield(app.UIFigure, 'numeric');
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @maxEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BG_globalThresholding(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end