classdef PrinCut < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SaveLoadresultPanel            matlab.ui.container.Panel
        resetGUITButton                matlab.ui.control.Button
        cleanlabelButton               matlab.ui.control.Button
        loadresultButton               matlab.ui.control.Button
        saveresultButton               matlab.ui.control.Button
        readyLamp                      matlab.ui.control.Lamp
        readyLampLabel                 matlab.ui.control.Label
        ManualrefinePanel              matlab.ui.container.Panel
        combineZButton                 matlab.ui.control.Button
        RecBrushXButton                matlab.ui.control.Button
        AddcelldilatesizeEditField     matlab.ui.control.NumericEditField
        AddcelldilatesizeEditFieldLabel  matlab.ui.control.Label
        capacitydesignEditField        matlab.ui.control.NumericEditField
        capacitydesignEditFieldLabel   matlab.ui.control.Label
        cropsizeEditField              matlab.ui.control.NumericEditField
        cropsizeEditFieldLabel         matlab.ui.control.Label
        uncertaincellButton            matlab.ui.control.Button
        labeloverlayLabel              matlab.ui.control.Label
        visLabelCheckBox               matlab.ui.control.CheckBox
        smartclickcorrectionLabel      matlab.ui.control.Label
        smartSeedCheckBox              matlab.ui.control.CheckBox
        picknextcellButton             matlab.ui.control.Button
        mergetwocellsCButton           matlab.ui.control.Button
        splitacellVButton              matlab.ui.control.Button
        deleteacellDButton             matlab.ui.control.Button
        addacellFButton                matlab.ui.control.Button
        VisulizationPanel              matlab.ui.container.Panel
        zSlider                        matlab.ui.control.Slider
        zsliceEditField                matlab.ui.control.NumericEditField
        lastzsliceButton               matlab.ui.control.Button
        nextzsliceButton               matlab.ui.control.Button
        MainAxes                       matlab.ui.control.UIAxes
        LoaddataPanel                  matlab.ui.container.Panel
        GPUvisulizationLabel           matlab.ui.control.Label
        gpuCheckBox                    matlab.ui.control.CheckBox
        maxintensityEditField          matlab.ui.control.NumericEditField
        maxintensityEditFieldLabel     matlab.ui.control.Label
        minintensityEditField          matlab.ui.control.NumericEditField
        minintensityEditFieldLabel     matlab.ui.control.Label
        getbackgroundButton            matlab.ui.control.Button
        loaddataButton                 matlab.ui.control.Button
        PreprocessPanel                matlab.ui.container.Panel
        removenoisysmoothfactorLabel   matlab.ui.control.Label
        smallsmrmCheckBox              matlab.ui.control.CheckBox
        calculateprincipalcurvatureButton  matlab.ui.control.Button
        zscorethresholdEditField       matlab.ui.control.NumericEditField
        zscorethresholdEditFieldLabel  matlab.ui.control.Label
        maxsmoothfactorEditField       matlab.ui.control.NumericEditField
        maxsmoothfactorEditFieldLabel  matlab.ui.control.Label
        smoothfactorEditField          matlab.ui.control.NumericEditField
        smoothfactorEditFieldLabel     matlab.ui.control.Label
        maxcellsizeEditField           matlab.ui.control.NumericEditField
        maxcellsizeEditFieldLabel      matlab.ui.control.Label
        zxresolutionratioEditField     matlab.ui.control.NumericEditField
        zxresolutionratioEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        dat; % Description
        datVis;
        SZ;
        overlayVis;
        maxZ;
        smFactorLst;
        BG;
        PC_raw;
        FGmap;
        curvature_fisrt;
        refine_res;
        validIdPool;
        cellDiameter;
        additionalCmap=uint8(jet(32)*255);
        clickMap;
        shiftFactor=1;

        testMode=true;

        ImName;
        filePath;
        temp;
    end
    
    methods (Access = public)
        
        function updateDatVis(app)
            raw=uint8((app.dat-app.minintensityEditField.Value)./((app.maxintensityEditField.Value-app.minintensityEditField.Value)/255));
            raw=min(raw,204);
            if app.gpuCheckBox.Value
                app.datVis=gray2RGB_HD(raw);
            else
                raw=gather(raw);
                app.datVis=gray2RGB_HD(raw);
            end
            datShow(app);
        end

        function datShow(app)
            if app.zsliceEditField.Value<1
                app.zsliceEditField.Value=1;
            elseif app.zsliceEditField.Value>app.maxZ
                app.zsliceEditField.Value=app.maxZ;
            end
            app.zSlider.Value=app.zsliceEditField.Value;
            if isempty(app.overlayVis)||~app.visLabelCheckBox.Value
                imshow(app.datVis(:,:,:,app.zsliceEditField.Value),'Parent',app.MainAxes);
            else
                imshow(app.datVis(:,:,:,app.zsliceEditField.Value)+app.overlayVis(:,:,:,app.zsliceEditField.Value),'Parent',app.MainAxes);
            end
        end
        
        function updateSmLst(app)
            N=ceil(log(app.maxsmoothfactorEditField.Value/app.smoothfactorEditField.Value)/log(1.2));
            app.smFactorLst=1.2.^(0:N).*app.smoothfactorEditField.Value;
        end
        
        function visBG(app)
            if app.gpuCheckBox.Value
                app.overlayVis=gpuArray(zeros([app.SZ(1:2) 3 app.SZ(3)],"uint8"));
                app.overlayVis(:,:,2,:)=permute(uint8(app.BG*255),[ 1 2 4 3])*0.5;
            else
                app.overlayVis=zeros([app.SZ(1:2) 3 app.SZ(3)],"uint8");
                app.overlayVis(:,:,2,:)=permute(uint8(gather(app.BG)*255),[ 1 2 4 3])*0.5;
            end
            
            datShow(app);
        end

        function vis1zBG(app)
            app.overlayVis(:,:,2,app.zsliceEditField.Value)=app.BG(:,:,app.zsliceEditField.Value)*0.5;
            datShow(app);
        end

        function visLabel(app)
            app.overlayVis=[];
            app.overlayVis=label2RGB_HD_v2(app.refine_res);
            datShow(app);
        end
        
        function segmentation_1(app)
            %% don't remove noisy smooth factor
            preSeg(app)
            [app.PC_raw,app.FGmap,curvature_all]=PrcplCrvtr_scaleInvariant_3D_v9(app.dat,app.smFactorLst,app.zxresolutionratioEditField.Value,app.BG);
            app.curvature_fisrt=curvature_all(:,:,:,1);
            postSeg(app);
        end

        function segmentation_2(app)
            %% remove noisy smooth factor
            preSeg(app)
            [app.PC_raw,app.FGmap,curvature_all]=PrcplCrvtr_scaleInvariant_3D_v9d1(app.dat,app.smFactorLst,app.zxresolutionratioEditField.Value,app.BG);
            app.curvature_fisrt=curvature_all(:,:,:,1);
            postSeg(app)
        end

        function preSeg(app)
            app.BG=gather(app.BG);
            app.datVis=gather(app.datVis);
            app.dat=gather(app.dat);
            app.overlayVis=gather(app.overlayVis);
        end
        
        function postSeg(app)
            eig_res_3d=app.PC_raw-app.zscorethresholdEditField.Value;
            if app.zxresolutionratioEditField.Value>1.1
                gapMap=imdilate(eig_res_3d>0,strel('disk',1));
            else
                gapMap=eig_res_3d>0;
            end
            eig_res_3d=app.curvature_fisrt;
            eig_res_3d(gapMap)=max(eig_res_3d(gapMap),1e-3);
            eig_res_3d(~gapMap)=-1;
            seedMap=getSeedMap(eig_res_3d,app.FGmap,app.maxcellsizeEditField.Value);
            option.costDesign=2;
            option.zRatio=app.zxresolutionratioEditField.Value;
            if app.testMode
                app.refine_res=zeros(size(app.dat),'uint16');
            else
                [app.refine_res,~]=regionWiseAnalysis4d_Wei_v11(seedMap,eig_res_3d,app.dat,option);
            end
            app.refine_res=uint16(app.refine_res);
            if app.gpuCheckBox.Value
                app.datVis=gpuArray(app.datVis);
                app.dat=gpuArray(app.dat);
            else
                disp("No GPU");
            end
            app.clickMap=false(size(app.refine_res));
            visLabel(app);
            
            app.validIdPool=max(app.refine_res,[],'all')+1;
            com = bwconncomp(app.refine_res);
            if app.testMode
                app.cellDiameter=10;
            else
                cellSz=median(cellfun(@length,com.PixelIdxList))*app.zxresolutionratioEditField.Value;
                app.cellDiameter=ceil(cellSz^(1/3)*1.5);
            end
            app.cropsizeEditField.Value=app.cellDiameter;
        end

        function disableZAxis(app)
            app.zSlider.Enable=false;
            app.zsliceEditField.Enable=false;
            app.lastzsliceButton.Enable=false;
            app.nextzsliceButton.Enable=false;
        end
        
        function enableZAxis(app)
            app.zSlider.Enable=true;
            app.zsliceEditField.Enable=true;
            app.lastzsliceButton.Enable=true;
            app.nextzsliceButton.Enable=true;
        end

        function [x, y, z] = selectDataPoints(app, ax)
            disableZAxis(app);
            roi = drawpoint(ax);
            enableZAxis(app);
            try
                x = roi.Position(1);
                y = roi.Position(2);
                x=round(x);
                y=round(y);
                z=app.zsliceEditField.Value;
            catch
                x=[];
                y=[];
                z=[];
            end
        end
        
        function addLabel(app,cur_locs)
            colorId=randi([1 size(app.additionalCmap,1)]);
            [x,y,z]=ind2sub(app.SZ,cur_locs);
            ind=sub2ind([app.SZ(1:2) 3 app.SZ(3)],x,y,ones(size(x)),z);
            incre=app.SZ(1)*app.SZ(2);

            app.overlayVis(ind)=app.additionalCmap(colorId,1);
            ind=ind+incre;
            app.overlayVis(ind)=app.additionalCmap(colorId,2);
            ind=ind+incre;
            app.overlayVis(ind)=app.additionalCmap(colorId,3);

            app.additionalCmap(colorId,:)=[];
            if isempty(app.additionalCmap)
                app.additionalCmap=uint8(jet(32)*255);
            end

            datShow(app);
        end
        
        function deleteLabel(app,cur_locs)
            [x,y,z]=ind2sub(app.SZ,cur_locs);
            ind=sub2ind([app.SZ(1:2) 3 app.SZ(3)],x,y,ones(size(x)),z);
            incre=app.SZ(1)*app.SZ(2);

            app.overlayVis(ind)=0;
            ind=ind+incre;
            app.overlayVis(ind)=0;
            ind=ind+incre;
            app.overlayVis(ind)=0;

            datShow(app);
            datShow(app);
        end
        
        function seed_id = popValidIdPool(app)
            seed_id=app.validIdPool(1);
            if length(app.validIdPool)==1
                app.validIdPool=app.validIdPool+1;
            else
                app.validIdPool=app.validIdPool(2:end);
            end
        end
        
        function pushValidIdPool(app,seed_id)
            if seed_id>0
                app.validIdPool=[seed_id app.validIdPool];
            end
        end
        
        function splitCellNext(app)
            yxz=app.temp.yxz;
            % delete existing cell
            [x, y, z]= selectDataPoints(app,app.MainAxes);
            yxz(2)=sub2ind(app.SZ,y,x,z);

            if length(yxz)~=2 || app.refine_res(yxz(1))~=app.refine_res(yxz(1)) %% || app.refine_res(yxz(1))==0
                %%
                enableRefineButton(app);
                app.readyLamp.Color=[0 1 0];
                app.readyLampLabel.Text="ready";
                return;
            end

            deleteCellNext(app,yxz(1));
            seedMap=app.refine_res;
            % seed of first cell
            seed_id(1)=popValidIdPool(app);
            seedMap(yxz(1))=seed_id(1);
            % seed of second cell
            seed_id(2)=popValidIdPool(app);
            seedMap(yxz(2))=seed_id(2);
            %%
            option.shift=ceil(app.cellDiameter*[1 1 1/app.zxresolutionratioEditField.Value]*app.shiftFactor);
            option.costDesign=app.capacitydesignEditField.Value;
            option.smartSeedFlag=app.smartSeedCheckBox.Value;
            option.NotDiaFlag=true;
            %%
            for cnt=1:2
                comMap=get_local_area_manual_Wei(app.dat,seedMap,seed_id(cnt),app.curvature_fisrt,yxz(cnt),option.shift); 
                sMap = getSmartSeedStandalone(seed_id(cnt),comMap,option);
                valid_newLabel = sMap(:)>0;
                cur_locs = comMap.linerInd(valid_newLabel);
                seedMap(cur_locs) = seed_id(cnt);
                app.clickMap(yxz(cnt))=true;
            end

           option.smartSeedFlag=false;
            for cnt=1:2
                comMap=get_local_area_manual_Wei(app.dat,seedMap,seed_id(cnt),app.curvature_fisrt,yxz(cnt),option.shift); 
                [newLabel, comMaps_temp] =refineOneRegion_with_seed_forSmartSeed(seed_id(cnt),comMap,option);
                valid_newLabel = newLabel(:)>0;
                cur_locs = comMaps_temp.linerInd(valid_newLabel);
                app.refine_res(cur_locs) = seed_id(cnt);
                addLabel(app,cur_locs);
            end

            %%
            enableRefineButton(app);
            app.readyLamp.Color=[0 1 0];
            app.readyLampLabel.Text="ready";
        end
        
        function disableRefineButton(app)
            app.addacellFButton.Enable=false;
            app.addacellFButton.Visible=false;
            app.deleteacellDButton.Enable=false;
            app.deleteacellDButton.Visible=false;
            app.splitacellVButton.Enable=false;
            app.splitacellVButton.Visible=false;
            app.mergetwocellsCButton.Enable=false;
            app.mergetwocellsCButton.Visible=false;
            app.RecBrushXButton.Enable=false;
            app.RecBrushXButton.Visible=false;
            app.combineZButton.Enable=false;
            app.combineZButton.Visible=false;

            app.picknextcellButton.Enable=true;
            app.picknextcellButton.Visible=true;
        end
        
        function enableRefineButton(app)
            app.addacellFButton.Enable=true;
            app.addacellFButton.Visible=true;
            app.deleteacellDButton.Enable=true;
            app.deleteacellDButton.Visible=true;
            app.splitacellVButton.Enable=true;
            app.splitacellVButton.Visible=true;
            app.mergetwocellsCButton.Enable=true;
            app.mergetwocellsCButton.Visible=true;
            app.RecBrushXButton.Enable=true;
            app.RecBrushXButton.Visible=true;
            app.combineZButton.Enable=true;
            app.combineZButton.Visible=true;

            app.picknextcellButton.Enable=false;
            app.picknextcellButton.Visible=false;
        end
        
        function mergeCellNext(app)
            yxz=app.temp.yxz;
            %%
            [x, y, z] = selectDataPoints(app,app.MainAxes);
            yxz(2)=sub2ind(app.SZ,y,x,z);
            if length(yxz)~=2 %%|| app.refine_res(yxz(1))==0 || app.refine_res(yxz(2))==0 || app.refine_res(yxz(1))==app.refine_res(yxz(2))
                %%
                enableRefineButton(app);
                app.readyLamp.Color=[0 1 0];
                app.readyLampLabel.Text="ready";
                return;
            end
            %% delete existing cells
            locsMap_all=cell(2,1);
            for cnt=1:2
                locsMap_all{cnt}=deleteCellNext(app,yxz(cnt));
                if isempty(locsMap_all{cnt})
                    locsMap_all{cnt}=yxz(cnt);
                end
            end
            seed_id=popValidIdPool(app);
            for cnt=1:2
                app.refine_res(locsMap_all{cnt})=seed_id;
            end

            option.shift=ceil(app.cellDiameter*[1 1 1/app.zxresolutionratioEditField.Value]);
            option.costDesign=app.capacitydesignEditField.Value;
            option.NotDiaFlag=true;
            option.mergeFlag=true;

            comMap=get_local_area_manual_Wei(app.dat,app.refine_res,seed_id,app.curvature_fisrt,...
                [locsMap_all{1};locsMap_all{2}],option.shift); 
            [newLabel, comMaps_temp] =refineOneRegion_with_seed_forSmartSeed(seed_id,comMap,option);
            valid_newLabel = newLabel(:)>0;
            cur_locs = comMaps_temp.linerInd(valid_newLabel);
            app.refine_res(cur_locs) = seed_id;
            
            app.clickMap(yxz(1))=true;

            addLabel(app,cur_locs);

            %%
            enableRefineButton(app);
            app.readyLamp.Color=[0 1 0];
            app.readyLampLabel.Text="ready";
        end
        
        
        function loadAndVisData(app)
            try
            if app.gpuCheckBox.Value
                app.dat=gpuArray(single(tifread([app.filePath app.ImName])));
            else
                app.dat=single(tifread([app.filePath app.ImName]));
            end
            app.maxintensityEditField.Value=gather(double(max(app.dat,[],"all")));
            app.minintensityEditField.Value=gather(double(min(app.dat,[],"all")));
            app.zsliceEditField.Value=1;
            app.maxZ=size(app.dat,3);
            app.zSlider.Limits=[1 app.maxZ];
            app.zSlider.Value=app.zsliceEditField.Value;
            app.SZ=size(app.dat);
            % app.overlayVis=zeros([app.SZ(1:2) 3 app.SZ(3)],"uint8");
            app.overlayVis=[];
            updateSmLst(app);
            app.BG=true(app.SZ);
            updateDatVis(app);
            catch
                disp("data unavaliable");
            end
        end
        
        function addCellNext(app,yxz)
            if length(yxz)~=1 || app.refine_res(yxz(1))>0
                %%
                enableRefineButton(app);
                app.readyLamp.Color=[0 1 0];
                app.readyLampLabel.Text="ready";
                return;
            end
            seed_id=app.validIdPool(1);
            if length(app.validIdPool)==1
                app.validIdPool=app.validIdPool+1;
            else
                app.validIdPool=app.validIdPool(2:end);
            end
            option.zRatio=app.zxresolutionratioEditField.Value;
            option.shift=ceil(app.cellDiameter*[1 1 1/option.zRatio].*app.shiftFactor);
            option.costDesign=app.capacitydesignEditField.Value;
            option.smartSeedFlag=app.smartSeedCheckBox.Value;
            option.NotDiaFlag=true;
            option.DiaDSz=app.AddcelldilatesizeEditField.Value;

            app.refine_res(yxz)=seed_id;

            comMap=get_local_area_manual_Wei(app.dat,app.refine_res,seed_id,app.curvature_fisrt,yxz,option.shift); 
            [newLabel, comMaps_temp] =refineOneRegion_with_seed_forSmartSeed(seed_id,comMap,option);

            valid_newLabel = newLabel(:)>0;
            cur_locs = comMaps_temp.linerInd(valid_newLabel);
            app.refine_res(cur_locs) = seed_id;
            app.clickMap(yxz)=true;
            
            if ~isempty(cur_locs)
                addLabel(app,cur_locs);
            else
                addLabel(app,yxz);
            end
        end
        
        function cur_locs=deleteCellNext(app,yxz)
            if length(yxz)~=1
                %%
                enableRefineButton(app);
                app.readyLamp.Color=[0 1 0];
                app.readyLampLabel.Text="ready";
                return;
            end
            seed_id=app.refine_res(yxz);
            if seed_id>0
                shift=ceil(app.cellDiameter*[1 1 1/app.zxresolutionratioEditField.Value]*app.shiftFactor*2);
                [vidComp,linerInd, ~, ~] = crop3D(app.refine_res, yxz, shift);
    
                cur_locs = linerInd(vidComp==seed_id);
                app.refine_res(cur_locs) = 0;
                pushValidIdPool(app,seed_id);
                deleteLabel(app,cur_locs);

                app.clickMap(cur_locs)=false;
            else
                cur_locs=[];
            end
        end
        
        function combineCellNext(app)
            yxz=app.temp.yxz;
            %%
            [x, y, z] = selectDataPoints(app,app.MainAxes);
            yxz(2)=sub2ind(app.SZ,y,x,z);
            if length(yxz)~=2 %%|| app.refine_res(yxz(1))==0 || app.refine_res(yxz(2))==0 || app.refine_res(yxz(1))==app.refine_res(yxz(2))
                %%
                enableRefineButton(app);
                app.readyLamp.Color=[0 1 0];
                app.readyLampLabel.Text="ready";
                return;
            end
            %% delete existing cells
            locsMap_all=cell(2,1);
            for cnt=1:2
                locsMap_all{cnt}=deleteCellNext(app,yxz(cnt));
                if isempty(locsMap_all{cnt})
                    locsMap_all{cnt}=yxz(cnt);
                end
            end
            seed_id=popValidIdPool(app);
            cur_locs=[];
            for cnt=1:2
                app.refine_res(locsMap_all{cnt})=seed_id;
                cur_locs=[cur_locs;locsMap_all{cnt}];
            end

            app.refine_res(cur_locs) = seed_id;
            app.clickMap(yxz(1))=true;

            addLabel(app,cur_locs);

            %%
            enableRefineButton(app);
            app.readyLamp.Color=[0 1 0];
            app.readyLampLabel.Text="ready";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
            app.readyLamp.Color=[1 1 0];
            app.readyLampLabel.Text="busy";
            pause(0.01);
            %%
            [app.ImName,app.filePath] = uigetfile("*.tif*");
            % app.dat=tifread('E:\Data\crop_embryo_data_500x500x30x40\embryo_TM481.tif');
            loadAndVisData(app)
            %%
            app.readyLamp.Color=[0 1 0];
            app.readyLampLabel.Text="ready";
        end

        % Value changed function: minintensityEditField
        function minintensityEditFieldValueChanged(app, event)
            % value = app.minintensityEditField.Value;
            updateDatVis(app);
        end

        % Value changed function: maxintensityEditField
        function maxintensityEditFieldValueChanged(app, event)
            % value = app.maxintensityEditField.Value;
            updateDatVis(app);
        end

        % Value changed function: zsliceEditField
        function zsliceEditFieldValueChanged(app, event)
            % value = app.zsliceEditField.Value;
            datShow(app);
        end

        % Value changing function: zSlider
        function zSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue=round(changingValue);
            if app.zsliceEditField.Value~=changingValue
                app.zsliceEditField.Value=changingValue;
                datShow(app);
            end
        end

        % Value changed function: maxsmoothfactorEditField
        function maxsmoothfactorEditFieldValueChanged(app, event)
            % value = app.maxsmoothfactorEditField.Value;
            updateSmLst(app);
        end

        % Value changed function: smoothfactorEditField
        function smoothfactorEditFieldValueChanged(app, event)
            % value = app.smoothfactorEditField.Value;
            app.maxsmoothfactorEditField.Value=app.smoothfactorEditField.Value;
            updateSmLst(app);
        end

        % Button pushed function: getbackgroundButton
        function getbackgroundButtonPushed(app, event)
            backgroundMethod(app);
        end

        % Button pushed function: nextzsliceButton
        function nextzsliceButtonPushed(app, event)
            app.zsliceEditField.Value=min(app.zsliceEditField.Value+1,app.zsliceEditField.Limits(2));
            datShow(app);
        end

        % Button pushed function: lastzsliceButton
        function lastzsliceButtonPushed(app, event)
            app.zsliceEditField.Value=max(app.zsliceEditField.Value-1,1);
            datShow(app);
        end

        % Button pushed function: calculateprincipalcurvatureButton
        function calculateprincipalcurvatureButtonPushed(app, event)
            app.readyLamp.Color=[1 1 0];
            app.readyLampLabel.Text="busy";
            pause(0.01);

            if app.smallsmrmCheckBox.Value
                segmentation_2(app);
            else
                segmentation_1(app);
            end

            app.readyLamp.Color=[0 1 0];
            app.readyLampLabel.Text="ready";
        end

        % Value changed function: zscorethresholdEditField
        function zscorethresholdEditFieldValueChanged(app, event)
            value = app.zscorethresholdEditField.Value;
            if value>=2
                app.smallsmrmCheckBox.Value=false;
            else
                app.smallsmrmCheckBox.Value=true;
            end
        end

        % Button pushed function: addacellFButton
        function addacellFButtonPushed(app, event)
            if app.readyLampLabel.Text=="busy"
                return;
            else
                app.readyLamp.Color=[1 1 0];
                app.readyLampLabel.Text="busy";
                pause(0.01);
            end
            

            %%
            [x, y, z] = selectDataPoints(app,app.MainAxes);
            yxz=sub2ind(app.SZ,y,x,z);
            addCellNext(app,yxz);
            %%
            app.readyLamp.Color=[0 1 0];
            app.readyLampLabel.Text="ready";
        end

        % Button pushed function: deleteacellDButton
        function deleteacellDButtonPushed(app, event)
            if app.readyLampLabel.Text=="busy"
                return;
            else
                app.readyLamp.Color=[1 1 0];
                app.readyLampLabel.Text="busy";
                pause(0.01);
            end
            %%
            [x, y, z]= selectDataPoints(app,app.MainAxes);
            yxz=sub2ind(app.SZ,y,x,z);

            deleteCellNext(app,yxz);

            %%
            app.readyLamp.Color=[0 1 0];
            app.readyLampLabel.Text="ready";
        end

        % Button pushed function: splitacellVButton
        function splitacellVButtonPushed(app, event)
            app.readyLamp.Color=[1 1 0];
            app.readyLampLabel.Text="busy";
            pause(0.01);
            %%
            app.temp=[];
            app.temp.state="split";
            %%
            [x, y, z] = selectDataPoints(app,app.MainAxes);
            yxz(1)=sub2ind(app.SZ,y,x,z);
            app.temp.yxz=yxz;
            %%
            disableRefineButton(app);
        end

        % Button pushed function: mergetwocellsCButton
        function mergetwocellsCButtonPushed(app, event)
            app.readyLamp.Color=[1 1 0];
            app.readyLampLabel.Text="busy";
            pause(0.01);
            %%
            app.temp=[];
            app.temp.state="merge";
            %%
            yxz=zeros(2,1);
            [x, y, z] = selectDataPoints(app,app.MainAxes);
            yxz(1)=sub2ind(app.SZ,y,x,z);
            app.temp.yxz=yxz;
            %%
            disableRefineButton(app);
        end

        % Button pushed function: picknextcellButton
        function picknextcellButtonPushed(app, event)
            if app.temp.state=="split"
                splitCellNext(app);
            elseif app.temp.state=="merge"
                mergeCellNext(app);
            else
                combineCellNext(app);
            end
        end

        % Button pushed function: saveresultButton
        function saveresultButtonPushed(app, event)
            app.readyLamp.Color=[1 1 0];
            app.readyLampLabel.Text="busy";
            pause(0.01);
            %%
            
            app.refine_res=uint16(app.refine_res);
            
            res_refine_res=app.refine_res;
            res_curvature_fisrt=app.curvature_fisrt;
            res_filePath=app.filePath;
            res_ImName=app.ImName;
            res_validIdPool=app.validIdPool;
            res_cellDiameter=app.cellDiameter;
            res_clickMap=app.clickMap;
            res_zRatio=app.zxresolutionratioEditField.Value;

            cd(app.filePath);
            [~,f,~]=fileparts(app.ImName);

            try
                listOfVariables = who('-file', "annotation_"+f+".mat");
                if ismember('res_curvature_fisrt', listOfVariables)
                    save("annotation_"+f,"res_refine_res","res_validIdPool","res_clickMap","-append");
                end
            catch
                save("annotation_"+f,"res_zRatio","res_curvature_fisrt","res_refine_res","res_filePath","res_ImName","res_validIdPool","res_cellDiameter","res_clickMap","-v7.3");
            end

            %%
            app.readyLamp.Color=[0 1 0];
            app.readyLampLabel.Text="ready";
        end

        % Button pushed function: loadresultButton
        function loadresultButtonPushed(app, event)
            app.readyLamp.Color=[1 1 0];
            app.readyLampLabel.Text="busy";
            pause(0.01);
            %%
            [ImNameTemp,filePathTemp] = uigetfile("*.mat");
            if filePathTemp==0
                return
            end
            app.ImName=ImNameTemp;
            app.filePath=filePathTemp;
            load([app.filePath app.ImName],"res_curvature_fisrt","res_refine_res","res_ImName","res_cellDiameter","res_zRatio");

            try
                load([app.filePath app.ImName],"res_clickMap");
                app.clickMap=res_clickMap;
            catch
                app.clickMap=false(size(res_refine_res));
            end

            app.refine_res=rearrangeLabel(res_refine_res);
            app.curvature_fisrt=res_curvature_fisrt;
            app.ImName=res_ImName;
            app.validIdPool=max(app.refine_res,[],"all")+1;
            app.cellDiameter=res_cellDiameter;
            app.cropsizeEditField.Value=app.cellDiameter;
            try
                app.zxresolutionratioEditField.Value=res_zRatio;
            catch
                app.zxresolutionratioEditField.Value=5;
            end

            app.refine_res=uint16(app.refine_res);

            loadAndVisData(app)
            visLabel(app)
            %%
            app.readyLamp.Color=[0 1 0];
            app.readyLampLabel.Text="ready";
        end

        % Value changed function: visLabelCheckBox
        function visLabelCheckBoxValueChanged(app, event)
            % value = app.visLabelCheckBox.Value;
            datShow(app);
        end

        % Callback function: ManualrefinePanel, UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if ~isempty(app.dat)
                switch key
                    case "rightarrow"
                    nextzsliceButtonPushed(app, event);
                    case "leftarrow"
                    lastzsliceButtonPushed(app, event);
                    case "l"
                    app.visLabelCheckBox.Value=~app.visLabelCheckBox.Value;
                    datShow(app);
                    case "numpad0"
                    addacellFButtonPushed(app, event);
                    case "numpad1"
                    deleteacellDButtonPushed(app, event);
                    case "numpad2"
                    if app.splitacellVButton.Visible==true
                        splitacellVButtonPushed(app, event);
                    else
                        picknextcellButtonPushed(app, event);
                    end
                    case "numpad3"
                    if app.mergetwocellsCButton.Visible==true
                        mergetwocellsCButtonPushed(app, event);
                    else
                        picknextcellButtonPushed(app, event);
                    end

                    case "e"
                    nextzsliceButtonPushed(app, event);
                    case "q"
                    lastzsliceButtonPushed(app, event);
                    case "s"
                    nextzsliceButtonPushed(app, event);
                    case "a"
                    lastzsliceButtonPushed(app, event);
                    case "f"
                    addacellFButtonPushed(app, event);
                    case "d"
                    deleteacellDButtonPushed(app, event);
                    case "v"
                    if app.splitacellVButton.Visible==true
                        splitacellVButtonPushed(app, event);
                    else
                        picknextcellButtonPushed(app, event);
                    end
                    case "c"
                    if app.mergetwocellsCButton.Visible==true
                        mergetwocellsCButtonPushed(app, event);
                    else
                        picknextcellButtonPushed(app, event);
                    end
                    case "z"
                    if app.mergetwocellsCButton.Visible==true
                        combineZButtonPushed(app, event);
                    else
                        picknextcellButtonPushed(app, event);
                    end
                    case "x"
                    RecBrushXButtonPushed(app, event);
                    case "t"
                    resetGUITButtonPushed(app, event);
                    case "g"
                    app.visLabelCheckBox.Value=~app.visLabelCheckBox.Value;
                    datShow(app);
                    case "w"
                    app.visLabelCheckBox.Value=~app.visLabelCheckBox.Value;
                    datShow(app);
                end
            end
        end

        % Button pushed function: cleanlabelButton
        function cleanlabelButtonPushed(app, event)
            app.readyLamp.Color=[1 1 0];
            app.readyLampLabel.Text="busy";
            pause(0.01);
            %%
            msg = 'Clean the current label?';
            title = 'Clean label';
            selection = uiconfirm(app.UIFigure,msg,title, ...
               'Options',{'Yes','Cancel'}, ...
               'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Yes'
                    app.refine_res=zeros(size(app.refine_res),class(app.refine_res));
                    app.validIdPool=1;
                    app.clickMap=false(size(app.refine_res));
                    visLabel(app);
                case 'Cancel'
                    return
            end
            %%
            app.readyLamp.Color=[0 1 0];
            app.readyLampLabel.Text="ready";
        end

        % Button pushed function: resetGUITButton
        function resetGUITButtonPushed(app, event)
            app.readyLamp.Color=[1 1 0];
            app.readyLampLabel.Text="busy";
            pause(0.01);
            app.refine_res=rearrangeLabel(app.refine_res);
            app.refine_res=uint16(app.refine_res);
            app.validIdPool=max(app.refine_res,[],"all")+1;
            disp("current label number: "+(app.validIdPool-1))
            visLabel(app);
            enableRefineButton(app);
            enableZAxis(app);
            app.readyLamp.Color=[0 1 0];
            app.readyLampLabel.Text="ready";
        end

        % Button pushed function: uncertaincellButton
        function uncertaincellButtonPushed(app, event)
            if app.readyLampLabel.Text=="busy"
                return;
            else
                app.readyLamp.Color=[1 1 0];
                app.readyLampLabel.Text="busy";
                pause(0.01);
            end
            %%
            try
                uncertainCellLocation=readmatrix([app.filePath 'uncertainCell.txt']);
            catch
                uncertainCellLocation=[];
            end
            [x, y, z]= selectDataPoints(app,app.MainAxes);
            uncertainCellLocation=[uncertainCellLocation;[x y z]];
            writematrix(uncertainCellLocation,[app.filePath 'uncertainCell.txt']);
            %%
            app.readyLamp.Color=[0 1 0];
            app.readyLampLabel.Text="ready";
        end

        % Value changed function: cropsizeEditField
        function cropsizeEditFieldValueChanged(app, event)
            app.cellDiameter = app.cropsizeEditField.Value;
        end

        % Button pushed function: RecBrushXButton
        function RecBrushXButtonPushed(app, event)
            if app.readyLampLabel.Text=="busy"
                return;
            else
                app.readyLamp.Color=[1 1 0];
                app.readyLampLabel.Text="busy";
                pause(0.01);
            end
            

            %%
            [x, y, z] = selectDataPoints(app,app.MainAxes);
            L=app.AddcelldilatesizeEditField.Value;

            xLst=max(x-L,1):min(x+L,app.SZ(1));
            yLst=max(y-L,1):min(y+L,app.SZ(2));
            [Y,X,Z] = meshgrid(yLst,xLst,z);
            
            yxz=sub2ind(app.SZ,Y,X,Z);
            yxz=yxz(:);
            yxz(app.refine_res(yxz)>0)=[];

            seed_id=popValidIdPool(app);
            app.refine_res(yxz) = seed_id;
            addLabel(app,yxz);
            app.clickMap(sub2ind(app.SZ,y,x,z))=true;

            %%
            app.readyLamp.Color=[0 1 0];
            app.readyLampLabel.Text="ready";
        end

        % Button pushed function: combineZButton
        function combineZButtonPushed(app, event)
            app.readyLamp.Color=[1 1 0];
            app.readyLampLabel.Text="busy";
            pause(0.01);
            %%
            app.temp=[];
            app.temp.state="combine";
            %%
            yxz=zeros(2,1);
            [x, y, z] = selectDataPoints(app,app.MainAxes);
            yxz(1)=sub2ind(app.SZ,y,x,z);
            app.temp.yxz=yxz;
            %%
            disableRefineButton(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.PreprocessPanel = uipanel(app.UIFigure);
            app.PreprocessPanel.Title = 'Preprocess';
            app.zxresolutionratioEditFieldLabel = uilabel(app.PreprocessPanel);
            app.zxresolutionratioEditFieldLabel.Text = 'z/x resolution ratio';
            app.zxresolutionratioEditField = uieditfield(app.PreprocessPanel, 'numeric');
            app.zxresolutionratioEditField.Value = 1;
            app.maxcellsizeEditFieldLabel = uilabel(app.PreprocessPanel);
            app.maxcellsizeEditFieldLabel.Enable = 'off';
            app.maxcellsizeEditFieldLabel.Text = 'max cell size';
            app.maxcellsizeEditField = uieditfield(app.PreprocessPanel, 'numeric');
            app.maxcellsizeEditField.ValueDisplayFormat = '%.0f';
            app.maxcellsizeEditField.Enable = 'off';
            app.maxcellsizeEditField.Value = 10000;
            app.smoothfactorEditFieldLabel = uilabel(app.PreprocessPanel);
            app.smoothfactorEditFieldLabel.Text = 'smooth factor';
            app.smoothfactorEditField = uieditfield(app.PreprocessPanel, 'numeric');
            app.smoothfactorEditField.ValueChangedFcn = createCallbackFcn(app, @smoothfactorEditFieldValueChanged, true);
            app.smoothfactorEditField.Value = 3;
            app.maxsmoothfactorEditFieldLabel = uilabel(app.PreprocessPanel);
            app.maxsmoothfactorEditFieldLabel.Enable = 'off';
            app.maxsmoothfactorEditFieldLabel.Text = 'max smooth factor';
            app.maxsmoothfactorEditField = uieditfield(app.PreprocessPanel, 'numeric');
            app.maxsmoothfactorEditField.ValueChangedFcn = createCallbackFcn(app, @maxsmoothfactorEditFieldValueChanged, true);
            app.maxsmoothfactorEditField.Enable = 'off';
            app.maxsmoothfactorEditField.Value = 3;
            app.zscorethresholdEditFieldLabel = uilabel(app.PreprocessPanel);
            app.zscorethresholdEditFieldLabel.Enable = 'off';
            app.zscorethresholdEditFieldLabel.Text = 'z score threshold';
            app.zscorethresholdEditField = uieditfield(app.PreprocessPanel, 'numeric');
            app.zscorethresholdEditField.ValueChangedFcn = createCallbackFcn(app, @zscorethresholdEditFieldValueChanged, true);
            app.zscorethresholdEditField.Enable = 'off';
            app.zscorethresholdEditField.Value = 2;
            app.calculateprincipalcurvatureButton = uibutton(app.PreprocessPanel, 'push');
            app.calculateprincipalcurvatureButton.ButtonPushedFcn = createCallbackFcn(app, @calculateprincipalcurvatureButtonPushed, true);
            app.calculateprincipalcurvatureButton.Text = 'calculate principal curvature';
            app.smallsmrmCheckBox = uicheckbox(app.PreprocessPanel);
            app.smallsmrmCheckBox.Text = '';
            app.removenoisysmoothfactorLabel = uilabel(app.PreprocessPanel);
            app.removenoisysmoothfactorLabel.Text = 'remove noisy smooth factor';
            app.LoaddataPanel = uipanel(app.UIFigure);
            app.LoaddataPanel.Title = 'Load data';
            app.loaddataButton = uibutton(app.LoaddataPanel, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.getbackgroundButton = uibutton(app.LoaddataPanel, 'push');
            app.getbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @getbackgroundButtonPushed, true);
            app.getbackgroundButton.Enable = 'off';
            app.getbackgroundButton.Text = 'get background';
            app.minintensityEditFieldLabel = uilabel(app.LoaddataPanel);
            app.minintensityEditFieldLabel.Text = 'min intensity';
            app.minintensityEditField = uieditfield(app.LoaddataPanel, 'numeric');
            app.minintensityEditField.ValueChangedFcn = createCallbackFcn(app, @minintensityEditFieldValueChanged, true);
            app.maxintensityEditFieldLabel = uilabel(app.LoaddataPanel);
            app.maxintensityEditFieldLabel.Text = 'max intensity';
            app.maxintensityEditField = uieditfield(app.LoaddataPanel, 'numeric');
            app.maxintensityEditField.ValueChangedFcn = createCallbackFcn(app, @maxintensityEditFieldValueChanged, true);
            app.gpuCheckBox = uicheckbox(app.LoaddataPanel);
            app.gpuCheckBox.Text = '';
            app.GPUvisulizationLabel = uilabel(app.LoaddataPanel);
            app.GPUvisulizationLabel.Text = 'GPU visulization';
            app.VisulizationPanel = uipanel(app.UIFigure);
            app.VisulizationPanel.Title = 'Visulization';
            app.MainAxes = uiaxes(app.VisulizationPanel);
            app.nextzsliceButton = uibutton(app.VisulizationPanel, 'push');
            app.nextzsliceButton.ButtonPushedFcn = createCallbackFcn(app, @nextzsliceButtonPushed, true);
            app.nextzsliceButton.Icon = fullfile(pathToMLAPP, 'figures', 'rightIcon.png');
            app.nextzsliceButton.Text = '';
            app.lastzsliceButton = uibutton(app.VisulizationPanel, 'push');
            app.lastzsliceButton.ButtonPushedFcn = createCallbackFcn(app, @lastzsliceButtonPushed, true);
            app.lastzsliceButton.Icon = fullfile(pathToMLAPP, 'figures', 'leftIcon.png');
            app.lastzsliceButton.Text = '';
            app.zsliceEditField = uieditfield(app.VisulizationPanel, 'numeric');
            app.zsliceEditField.ValueChangedFcn = createCallbackFcn(app, @zsliceEditFieldValueChanged, true);
            app.zsliceEditField.Value = 1;
            app.zSlider = uislider(app.VisulizationPanel);
            app.zSlider.Limits = [1 10];
            app.zSlider.MajorTicks = [];
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @zSliderValueChanging, true);
            app.zSlider.MinorTicks = [];
            app.zSlider.Value = 1;
            app.ManualrefinePanel = uipanel(app.UIFigure);
            app.ManualrefinePanel.Title = 'Manual refine';
            app.ManualrefinePanel.SizeChangedFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.addacellFButton = uibutton(app.ManualrefinePanel, 'push');
            app.addacellFButton.ButtonPushedFcn = createCallbackFcn(app, @addacellFButtonPushed, true);
            app.addacellFButton.Text = 'add a cell (F)';
            app.deleteacellDButton = uibutton(app.ManualrefinePanel, 'push');
            app.deleteacellDButton.ButtonPushedFcn = createCallbackFcn(app, @deleteacellDButtonPushed, true);
            app.deleteacellDButton.Text = 'delete a cell (D)';
            app.splitacellVButton = uibutton(app.ManualrefinePanel, 'push');
            app.splitacellVButton.ButtonPushedFcn = createCallbackFcn(app, @splitacellVButtonPushed, true);
            app.splitacellVButton.Text = 'split a cell (V)';
            app.mergetwocellsCButton = uibutton(app.ManualrefinePanel, 'push');
            app.mergetwocellsCButton.ButtonPushedFcn = createCallbackFcn(app, @mergetwocellsCButtonPushed, true);
            app.mergetwocellsCButton.Text = 'merge two cells (C)';
            app.picknextcellButton = uibutton(app.ManualrefinePanel, 'push');
            app.picknextcellButton.ButtonPushedFcn = createCallbackFcn(app, @picknextcellButtonPushed, true);
            app.picknextcellButton.Enable = 'off';
            app.picknextcellButton.Visible = 'off';
            app.picknextcellButton.Text = 'pick next cell';
            app.smartSeedCheckBox = uicheckbox(app.ManualrefinePanel);
            app.smartSeedCheckBox.Text = '';
            app.smartSeedCheckBox.Value = true;
            app.smartclickcorrectionLabel = uilabel(app.ManualrefinePanel);
            app.smartclickcorrectionLabel.Text = 'smart click correction';
            app.visLabelCheckBox = uicheckbox(app.ManualrefinePanel);
            app.visLabelCheckBox.ValueChangedFcn = createCallbackFcn(app, @visLabelCheckBoxValueChanged, true);
            app.visLabelCheckBox.Text = '';
            app.visLabelCheckBox.Value = true;
            app.labeloverlayLabel = uilabel(app.ManualrefinePanel);
            app.labeloverlayLabel.Text = 'label overlay';
            app.uncertaincellButton = uibutton(app.ManualrefinePanel, 'push');
            app.uncertaincellButton.ButtonPushedFcn = createCallbackFcn(app, @uncertaincellButtonPushed, true);
            app.uncertaincellButton.Text = 'uncertain cell';
            app.cropsizeEditFieldLabel = uilabel(app.ManualrefinePanel);
            app.cropsizeEditFieldLabel.Text = 'crop size';
            app.cropsizeEditField = uieditfield(app.ManualrefinePanel, 'numeric');
            app.cropsizeEditField.ValueChangedFcn = createCallbackFcn(app, @cropsizeEditFieldValueChanged, true);
            app.capacitydesignEditFieldLabel = uilabel(app.ManualrefinePanel);
            app.capacitydesignEditFieldLabel.Text = 'capacity design';
            app.capacitydesignEditField = uieditfield(app.ManualrefinePanel, 'numeric');
            app.capacitydesignEditField.Value = 2;
            app.AddcelldilatesizeEditFieldLabel = uilabel(app.ManualrefinePanel);
            app.AddcelldilatesizeEditFieldLabel.Text = 'Add cell dilate size';
            app.AddcelldilatesizeEditField = uieditfield(app.ManualrefinePanel, 'numeric');
            app.RecBrushXButton = uibutton(app.ManualrefinePanel, 'push');
            app.RecBrushXButton.ButtonPushedFcn = createCallbackFcn(app, @RecBrushXButtonPushed, true);
            app.RecBrushXButton.Text = 'Rec Brush (X)';
            app.combineZButton = uibutton(app.ManualrefinePanel, 'push');
            app.combineZButton.ButtonPushedFcn = createCallbackFcn(app, @combineZButtonPushed, true);
            app.combineZButton.Text = 'combine (Z)';
            app.readyLampLabel = uilabel(app.UIFigure);
            app.readyLampLabel.Text = 'ready';
            app.readyLamp = uilamp(app.UIFigure);
            app.SaveLoadresultPanel = uipanel(app.UIFigure);
            app.SaveLoadresultPanel.Title = 'Save/Load result';
            app.saveresultButton = uibutton(app.SaveLoadresultPanel, 'push');
            app.saveresultButton.ButtonPushedFcn = createCallbackFcn(app, @saveresultButtonPushed, true);
            app.saveresultButton.Text = 'save result';
            app.loadresultButton = uibutton(app.SaveLoadresultPanel, 'push');
            app.loadresultButton.ButtonPushedFcn = createCallbackFcn(app, @loadresultButtonPushed, true);
            app.loadresultButton.Text = 'load result';
            app.cleanlabelButton = uibutton(app.SaveLoadresultPanel, 'push');
            app.cleanlabelButton.ButtonPushedFcn = createCallbackFcn(app, @cleanlabelButtonPushed, true);
            app.cleanlabelButton.Text = 'clean label';
            app.resetGUITButton = uibutton(app.SaveLoadresultPanel, 'push');
            app.resetGUITButton.ButtonPushedFcn = createCallbackFcn(app, @resetGUITButtonPushed, true);
            app.resetGUITButton.Text = 'reset GUI (T)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PrinCut

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end